classdef my_DOR_distrib_viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SigmaEditField               matlab.ui.control.NumericEditField
        SigmaEditFieldLabel          matlab.ui.control.Label
        ReverseorderCheckBox         matlab.ui.control.CheckBox
        ComponentsPanel              matlab.ui.container.Panel
        Label_2                      matlab.ui.control.Label
        CheckBoxComp10               matlab.ui.control.CheckBox
        CheckBoxComp9                matlab.ui.control.CheckBox
        CheckBoxComp8                matlab.ui.control.CheckBox
        CheckBoxComp7                matlab.ui.control.CheckBox
        CheckBoxComp6                matlab.ui.control.CheckBox
        Label                        matlab.ui.control.Label
        CheckBoxComp5                matlab.ui.control.CheckBox
        CheckBoxComp4                matlab.ui.control.CheckBox
        CheckBoxComp3                matlab.ui.control.CheckBox
        CheckBoxComp2                matlab.ui.control.CheckBox
        CheckBoxComp1                matlab.ui.control.CheckBox
        SelectionDropDownDistY       matlab.ui.control.DropDown
        YaxisLabel                   matlab.ui.control.Label
        SelectionDropDownDistX       matlab.ui.control.DropDown
        XaxisLabel                   matlab.ui.control.Label
        MinSelectedWeights           matlab.ui.control.NumericEditField
        MinSelectedEditFieldLabel_6  matlab.ui.control.Label
        MaxSelectedWeights           matlab.ui.control.NumericEditField
        MaxSelectedEditFieldLabel_6  matlab.ui.control.Label
        SliceSlider                  matlab.ui.control.Slider
        SliceSliderLabel             matlab.ui.control.Label
        MaxSelectedFrqDiff           matlab.ui.control.NumericEditField
        MaxSelectedEditFieldLabel_5  matlab.ui.control.Label
        MinSelectedFrqDiff           matlab.ui.control.NumericEditField
        MinSelectedEditFieldLabel_5  matlab.ui.control.Label
        SelectionDropDownFrqDiff     matlab.ui.control.DropDown
        SelectionDropDown_2Label     matlab.ui.control.Label
        LabelNJ                      matlab.ui.control.Label
        LabelNI                      matlab.ui.control.Label
        MyDORR1R2distributionviewerLabel  matlab.ui.control.Label
        MaxSelectedR2                matlab.ui.control.NumericEditField
        MaxSelectedEditFieldLabel_4  matlab.ui.control.Label
        MinSelectedR2                matlab.ui.control.NumericEditField
        MinSelectedEditFieldLabel_4  matlab.ui.control.Label
        MaxSelectedR1                matlab.ui.control.NumericEditField
        MaxSelectedEditFieldLabel_3  matlab.ui.control.Label
        MinSelectedR1                matlab.ui.control.NumericEditField
        MinSelectedEditFieldLabel_3  matlab.ui.control.Label
        MaxSelectedDdelta            matlab.ui.control.NumericEditField
        MaxSelectedEditFieldLabel_2  matlab.ui.control.Label
        MinSelectedDdelta            matlab.ui.control.NumericEditField
        MinSelectedEditFieldLabel_2  matlab.ui.control.Label
        MaxSelectedDiso              matlab.ui.control.NumericEditField
        MaxSelectedEditFieldLabel    matlab.ui.control.Label
        MinSelectedDiso              matlab.ui.control.NumericEditField
        MinSelectedEditFieldLabel    matlab.ui.control.Label
        ParametersPanel              matlab.ui.container.Panel
        NfreqEditField               matlab.ui.control.NumericEditField
        NFrqdiffLabel                matlab.ui.control.Label
        FrqdiffEditField             matlab.ui.control.NumericEditField
        FrqdiffEditFieldLabel        matlab.ui.control.Label
        OmegafrqEditField            matlab.ui.control.NumericEditField
        OmegafrqEditFieldLabel       matlab.ui.control.Label
        NbinhistEditField            matlab.ui.control.NumericEditField
        NbinhistEditFieldLabel       matlab.ui.control.Label
        DrawButton                   matlab.ui.control.Button
        SelectionDropDown            matlab.ui.control.DropDown
        SelectionDropDownLabel       matlab.ui.control.Label
        MapDropDown                  matlab.ui.control.DropDown
        MapDropDownLabel             matlab.ui.control.Label
        LoaddataPanel                matlab.ui.container.Panel
        Loadpdata_mddButton          matlab.ui.control.Button
        MethodDropDown               matlab.ui.control.DropDown
        MethodDropDownLabel          matlab.ui.control.Label
        UIAxesProfDistY              matlab.ui.control.UIAxes
        UIAxesDist2D                 matlab.ui.control.UIAxes
        UIAxesProfDistX              matlab.ui.control.UIAxes
        UIAxesWeights                matlab.ui.control.UIAxes
        UIAxesFrqDiff                matlab.ui.control.UIAxes
        UIAxesMapSelected            matlab.ui.control.UIAxes
        UIAxesR2                     matlab.ui.control.UIAxes
        UIAxesR1                     matlab.ui.control.UIAxes
        UIAxesDdelta                 matlab.ui.control.UIAxes
        UIAxesDiso                   matlab.ui.control.UIAxes
        UIAxesMap                    matlab.ui.control.UIAxes
    end


    properties (Access = public)
        s % structure storing all info and data
    end

    methods (Access = private)

        function [] = display_map(app)
            app.s.map_selected = app.MapDropDown.Value;
            if strcmp(app.s.map_selected,'fractions')
                if app.s.dim3D == 1
                    pmaps.fractions = permute(app.s.pmaps.fractions,[2 3 4 1]);
                    imagesc(app.UIAxesMap,rescale(squeeze(pmaps.fractions(:,:,app.s.slice,:))));
                    app.UIAxesMap.XLim =[1,size(pmaps.fractions,2)]; app.UIAxesMap.YLim = [1,size(pmaps.fractions,1)];
                else
                    pmaps.fractions = permute(app.s.pmaps.fractions,[2 3 1]);
                    imagesc(app.UIAxesMap,rescale(pmaps.fractions));
                    app.UIAxesMap.XLim =[1,size(pmaps.fractions,2)]; app.UIAxesMap.YLim = [1,size(pmaps.fractions,1)];
                end
            end
            if strcmp(app.s.map_selected,'mdiso')
                clim.mdiso = 1*[0 0.7]; % Multiplied with s0 below
                smax = quantile(app.s.pmaps.mdiso(app.s.pmaps.mdiso>0),0.999,'all');
                clim.mdiso = smax*clim.mdiso;
                if app.s.dim3D == 1
                    imagesc(app.UIAxesMap,app.s.pmaps.mdiso(:,:,app.s.slice),clim.mdiso);
                    app.UIAxesMap.XLim =[1,size(app.s.pmaps.mdiso,2)]; app.UIAxesMap.YLim = [1,size(app.s.pmaps.mdiso,1)];
                else
                    imagesc(app.UIAxesMap,app.s.pmaps.mdiso,clim.mdiso);
                    app.UIAxesMap.XLim =[1,size(app.s.pmaps.mdiso,2)]; app.UIAxesMap.YLim = [1,size(app.s.pmaps.mdiso,1)];
                end
                colormap(app.UIAxesMap,'gray');
            end
            %             app.UIAxesMap.DataAspectRatio = [1 1 1];
        end

        function [] = display_hist(app)
            ni = round(app.s.coord_point(:,2)); nj = round(app.s.coord_point(:,1)); nk= app.s.slice;
            % Prepare variables
            if strcmp(app.s.method,'dtor1r2d')
                [app.s.par,app.s.perp, app.s.theta, app.s.phi, app.s.d0, app.s.rpar, app.s.rperp, app.s.r1, app.s.r2, app.s.w, app.s.nComp] = my_dtor1r2d_par2dist(app,ni,nj,nk,...
                    app.s.dpar_4d,app.s.dperp_4d,app.s.theta_4d,...
                    app.s.phi_4d,app.s.d0_4d,app.s.rpar_4d,app.s.rperp_4d,...
                    app.s.r1_4d,app.s.r2_4d,...
                    app.s.w_4d);

                app.s.omega_v =app.OmegafrqEditField.Value;
                [app,dparo,dperpo] = my_dtor1r2d_dist2parso(app,app.s.omega_v);
                [~,app.s.disoo,app.s.danisoo,app.s.dratioo,app.s.ddeltao,app.s.sdanisoo,app.s.sddeltao] = my_dtd_pars2dpars(app,dparo,dperpo);
            end

            if strcmp(app.s.method,'dtr1r2d')
                [app.s.par,app.s.perp, app.s.theta, app.s.phi, app.s.r1, app.s.r2, app.s.w, app.s.nComp] = my_dtr1r2d_par2dist(app,ni,nj,nk,...
                    app.s.dpar_4d,app.s.dperp_4d,...
                    app.s.theta_4d, app.s.phi_4d,...
                    app.s.r1_4d,app.s.r2_4d,...
                    app.s.w_4d);

                [~,app.s.disoo,app.s.danisoo,app.s.dratioo,app.s.ddeltao,app.s.sdanisoo,app.s.sddeltao] = my_dtd_pars2dpars(app,app.s.par,app.s.perp);
            end

            % display Histograms
            app.s.Nbins = app.NbinhistEditField.Value;
            if strcmp(app.s.method,'dtor1r2d')
                app.s.edgesDiso = 0:5/(app.s.Nbins-1):5;
                app.s.edgesDdelta = 0:1/(app.s.Nbins-1):1;
                app.s.edgesR1 = app.s.opt.opt.dtor1r2d.r1min:(app.s.opt.opt.dtor1r2d.r1max-app.s.opt.opt.dtor1r2d.r1min)/(app.s.Nbins-1):app.s.opt.opt.dtor1r2d.r1max;
                app.s.edgesR2 = app.s.opt.opt.dtor1r2d.r2min:(app.s.opt.opt.dtor1r2d.r2max-app.s.opt.opt.dtor1r2d.r2min)/(app.s.Nbins-1):app.s.opt.opt.dtor1r2d.r2max;
                app.s.edgesWeights = 0:max(app.s.w(:))/(app.s.Nbins-1):max(app.s.w(:));
            else
                app.s.edgesDiso = 0:2.5/(app.s.Nbins-1):5;
                app.s.edgesDdelta = 0:1/(app.s.Nbins-1):1;
                app.s.edgesR1 = 0:5/(app.s.Nbins-1):5;
                app.s.edgesR2 = 0:100/(app.s.Nbins-1):100;
                app.s.edgesWeights = 0:max(app.s.w(:))/(app.s.Nbins-1):max(app.s.w(:));
            end

            cla(app.UIAxesDiso); cla(app.UIAxesDdelta); cla(app.UIAxesR1); cla(app.UIAxesR2); cla(app.UIAxesWeights); cla(app.UIAxesFrqDiff);

            bar(app.UIAxesDiso,app.s.edgesDiso,dist2bar(app,app.s.edgesDiso,app.s.disoo./1e-9,app.s.w),'FaceColor',[0 0 1],'EdgeColor','none','BarWidth', 0.85)
            bar(app.UIAxesDdelta,app.s.edgesDdelta,dist2bar(app,app.s.edgesDdelta,app.s.sddeltao,app.s.w),'FaceColor',[0 0 1],'EdgeColor','none','BarWidth', 0.85)
            bar(app.UIAxesR1,app.s.edgesR1,dist2bar(app,app.s.edgesR1,app.s.r1,app.s.w),'FaceColor',[0 0 1],'EdgeColor','none','BarWidth', 0.85)
            bar(app.UIAxesR2,app.s.edgesR2,dist2bar(app,app.s.edgesR2,app.s.r2,app.s.w),'FaceColor',[0 0 1],'EdgeColor','none','BarWidth', 0.85)
            bar(app.UIAxesWeights,app.s.edgesWeights,dist2bar(app,app.s.edgesWeights,app.s.w(app.s.w~=0),app.s.w(app.s.w~=0)),'FaceColor',[0 0 1],'EdgeColor','none','BarWidth', 0.85)

            if strcmp(app.s.method,'dtor1r2d')
                if strcmp(app.SelectionDropDownFrqDiff.Value,'Diso')
                    app.s.edgesFrqDiff = -2.5:5/(app.s.Nbins-1):2.5;
                    %                     cla(app.UIAxesFrqDiff);
                    app.s.omega_v_diff =app.FrqdiffEditField.Value;
                    [app,dparo_diff,dperpo_diff] = my_dtor1r2d_dist2parso(app,app.s.omega_v_diff);

                    [~,app.s.disoo_diff,~,~,~,~,app.s.sddeltao_diff] = my_dtd_pars2dpars(app,dparo_diff,dperpo_diff);
                    bar(app.UIAxesFrqDiff,app.s.edgesFrqDiff,dist2bar(app,app.s.edgesFrqDiff,(app.s.disoo-app.s.disoo_diff)./1e-9,app.s.w),'FaceColor',[0 0 1],'EdgeColor','none','BarWidth', 0.85)
                end
                if strcmp(app.SelectionDropDownFrqDiff.Value,'Ddelta')
                    app.s.edgesFrqDiff = -1:2/(app.s.Nbins-1):1;
                    %                     cla(app.UIAxesFrqDiff);
                    app.s.omega_v_diff =app.FrqdiffEditField.Value;
                    [app,dparo_diff,dperpo_diff] = my_dtor1r2d_dist2parso(app,app.s.omega_v_diff);

                    [~,app.s.disoo_diff,~,~,~,~,app.s.sddeltao_diff] = my_dtd_pars2dpars(app,dparo_diff,dperpo_diff);
                    bar(app.UIAxesFrqDiff,app.s.edgesFrqDiff,dist2bar(app, app.s.edgesFrqDiff,app.s.sddeltao-app.s.sddeltao_diff,app.s.w),'FaceColor',[0 0 1],'EdgeColor','none','BarWidth', 0.85)
                end
            end

            % display limited histogram
            hold(app.UIAxesDiso, 'on');
            hold(app.UIAxesDdelta, 'on');
            hold(app.UIAxesR1, 'on');
            hold(app.UIAxesR2, 'on');
            hold(app.UIAxesFrqDiff, 'on');
            hold(app.UIAxesWeights, 'on');

            % Sort components to be able to select them
            Ncomponents = 10;
            if size(app.s.coord_point,1) ==1
                app.s.w_sort = reshape(app.s.w,Ncomponents,size(app.s.w,1)/Ncomponents);
                [~,sort_order]  = sort(app.s.w_sort,1,'descend');
                sort_order = reshape(sort_order,size(sort_order,1)*size(sort_order,2),1);
                limited_comp = ones(size(app.s.w));
            end
            if size(app.s.coord_point,1) > 1
                Npoints_sel= size(app.s.coord_point,1);
                app.s.w_sort = reshape(app.s.w,Ncomponents,size(app.s.w,1)/Ncomponents,Npoints_sel);
                [~,sort_order] = sort(app.s.w_sort,1,'descend');
                sort_order = reshape(sort_order,size(sort_order,1)*size(sort_order,2),size(sort_order,3));
                limited_comp = ones(size(app.s.w));
            end
            for ind_comp =1:10
                if eval(sprintf('app.CheckBoxComp%d.Value',ind_comp)) ==0
                    limited_comp(sort_order==ind_comp) =0;
                end
            end

            % Create limited vect based on boundaries
            disoo_limited = app.s.disoo>=(app.MinSelectedDiso.Value *1e-9) & app.s.disoo<=(app.MaxSelectedDiso.Value *1e-9);
            sddeltao_limited = app.s.sddeltao>=(app.MinSelectedDdelta.Value) & app.s.sddeltao<=(app.MaxSelectedDdelta.Value);
            r1_limited = (app.s.r1)>=(app.MinSelectedR1.Value) & (app.s.r1)<=(app.MaxSelectedR1.Value);
            r2_limited = (app.s.r2)>=(app.MinSelectedR2.Value) & (app.s.r2)<=(app.MaxSelectedR2.Value);
            if strcmp(app.s.method,'dtor1r2d')
                if strcmp(app.SelectionDropDownFrqDiff.Value,'Diso')
                    app.s.ddiff = app.s.disoo-app.s.disoo_diff;
                    ddiff_limited = app.s.ddiff>=(app.MinSelectedFrqDiff.Value*1e-9) & app.s.ddiff<=(app.MaxSelectedFrqDiff.Value*1e-9);
                end

                if strcmp(app.SelectionDropDownFrqDiff.Value,'Ddelta')
                    app.s.ddiff = app.s.sddeltao-app.s.sddeltao_diff;
                    ddiff_limited = app.s.ddiff>=(app.MinSelectedFrqDiff.Value) & app.s.ddiff<=(app.MaxSelectedFrqDiff.Value);
                end
            end
            % For the weight, remove the entire components
            weights_limited = (app.s.w)>=(app.MinSelectedWeights.Value) & (app.s.w)<=(app.MaxSelectedWeights.Value);

            if size(app.s.coord_point,1) ==1
                weights_limited = reshape(weights_limited,Ncomponents,size(weights_limited,1)/Ncomponents);
                [~,idx_zeros] = find(weights_limited==0);
                for indZ = 1:size(idx_zeros,1)
                    weights_limited(:,idx_zeros(indZ,1)) = 0;
                end
                weights_limited = weights_limited(:);
            end
            if size(app.s.coord_point,1) > 1
                Npoints_sel= size(app.s.coord_point,1);
                weights_limited = reshape(weights_limited,Ncomponents,size(weights_limited,1)/Ncomponents,Npoints_sel);
                [~,idx_zeros] = find(weights_limited==0);
                [idx1_zeros,idx2_zeros,idx3_zeros] = ind2sub(size(weights_limited),idx_zeros);
                for indZ = 1:size(idx1_zeros,1)
                    weights_limited(:,idx2_zeros(indZ,1),idx3_zeros(indZ,1)) = 0;
                end
                weights_limited = reshape(weights_limited,size(weights_limited,1)*size(weights_limited,2),size(weights_limited,3));
            end
            % For the weight, remove the entire components End

            if strcmp(app.s.method,'dtor1r2d')
                app.s.vect_limited = logical(disoo_limited.*sddeltao_limited.*r1_limited.*r2_limited.*ddiff_limited.*weights_limited.*limited_comp);
            end

            if strcmp(app.s.method,'dtr1r2d')
                app.s.vect_limited = logical(disoo_limited.*sddeltao_limited.*r1_limited.*r2_limited.*weights_limited.*limited_comp);
            end

            bar(app.UIAxesDiso,app.s.edgesDiso,dist2bar(app,app.s.edgesDiso,app.s.disoo(app.s.vect_limited)./1e-9,app.s.w(app.s.vect_limited)),'FaceColor',[1 0 0],'EdgeColor','none','BarWidth', 0.85)
            bar(app.UIAxesDdelta,app.s.edgesDdelta,dist2bar(app,app.s.edgesDdelta,app.s.sddeltao(app.s.vect_limited),app.s.w(app.s.vect_limited)),'FaceColor',[1 0 0],'EdgeColor','none','BarWidth', 0.85)
            bar(app.UIAxesR1,app.s.edgesR1,dist2bar(app,app.s.edgesR1,app.s.r1(app.s.vect_limited),app.s.w(app.s.vect_limited)),'FaceColor',[1 0 0],'EdgeColor','none','BarWidth', 0.85)
            bar(app.UIAxesR2,app.s.edgesR2,dist2bar(app,app.s.edgesR2,app.s.r2(app.s.vect_limited),app.s.w(app.s.vect_limited)),'FaceColor',[1 0 0],'EdgeColor','none','BarWidth', 0.85)
            w_limited = app.s.w(app.s.vect_limited);
            bar(app.UIAxesWeights,app.s.edgesWeights,dist2bar(app,app.s.edgesWeights,w_limited(w_limited~=0),w_limited(w_limited~=0)),'FaceColor',[1 0 0],'EdgeColor','none','BarWidth', 0.85)

            if strcmp(app.s.method,'dtor1r2d')
                if strcmp(app.SelectionDropDownFrqDiff.Value,'Diso')
                    bar(app.UIAxesFrqDiff,app.s.edgesFrqDiff,dist2bar(app,app.s.edgesFrqDiff,(app.s.disoo(app.s.vect_limited)-app.s.disoo_diff(app.s.vect_limited))./1e-9,app.s.w(app.s.vect_limited)),'FaceColor',[1 0 0],'EdgeColor','none','BarWidth', 0.85)
                end
                if strcmp(app.SelectionDropDownFrqDiff.Value,'Ddelta')
                    bar(app.UIAxesFrqDiff,app.s.edgesFrqDiff,dist2bar(app, app.s.edgesFrqDiff,app.s.sddeltao(app.s.vect_limited)-app.s.sddeltao_diff(app.s.vect_limited),app.s.w(app.s.vect_limited)),'FaceColor',[1 0 0],'EdgeColor','none','BarWidth', 0.85)
                end
            end

            % Display selection Map
            if strcmp(app.s.method,'dtr1r2d')
                diso_4d = (app.s.dpar_4d + 2*app.s.dperp_4d)/3;
                daniso_4d = (app.s.dpar_4d - app.s.dperp_4d)/3;
                sddelta_4d = msf_notfinite2zero((daniso_4d.^2)./(diso_4d.^2));

                disoo_limited_4d = diso_4d>=(app.MinSelectedDiso.Value *1e-9) & diso_4d<=(app.MaxSelectedDiso.Value *1e-9);
                sddeltao_limited_4d = sddelta_4d>=(app.MinSelectedDdelta.Value) & sddelta_4d<=(app.MaxSelectedDdelta.Value);
                r1_limited_4d = (app.s.r1_4d)>=(app.MinSelectedR1.Value) & (app.s.r1_4d)<=(app.MaxSelectedR1.Value);
                r2_limited_4d = (app.s.r2_4d)>=(app.MinSelectedR2.Value) & (app.s.r2_4d)<=(app.MaxSelectedR2.Value);
                weights_limited_4d = (app.s.w_4d)>=(app.MinSelectedWeights.Value) & (app.s.w_4d)<=(app.MaxSelectedWeights.Value);

                % Sort components to be able to select them
                Ncomponents = 10;
                app.s.w_4d_sort = reshape(app.s.w_4d,size(app.s.w_4d,1),size(app.s.w_4d,2),size(app.s.w_4d,3),Ncomponents,size(app.s.w_4d,4)/Ncomponents);
                [~,sort_order_4d] = sort(app.s.w_sort,4,'descend');
                sort_order_4d = reshape(sort_order_4d,size(sort_order_4d,1),size(sort_order_4d,2),size(sort_order_4d,3),size(sort_order_4d,4)*size(sort_order_4d,5));
                limited_comp_4d = ones(size(app.s.w_4d));
                for ind_comp =1:10
                    if eval(sprintf('app.CheckBoxComp%d.Value',ind_comp)) ==0
                        limited_comp_4d(sort_order_4d==ind_comp) =0;
                    end
                end

                mat_limited = logical(disoo_limited_4d.*sddeltao_limited_4d.*r1_limited_4d.*r2_limited_4d.*weights_limited_4d.*limited_comp_4d);
            end

            if strcmp(app.s.method,'dtor1r2d')
                app.s.dpar_4do = app.s.d0_4d - (app.s.d0_4d - app.s.dpar_4d)./(1 + app.s.omega_v.^2./app.s.rpar_4d.^2);
                app.s.dperp_4do = app.s.d0_4d - (app.s.d0_4d - app.s.dperp_4d)./(1 + app.s.omega_v.^2./app.s.rperp_4d.^2);
                diso_4d = (app.s.dpar_4do + 2*app.s.dperp_4do)/3;
                daniso_4d = (app.s.dpar_4do - app.s.dperp_4do)/3;
                sddelta_4d = msf_notfinite2zero((daniso_4d.^2)./(diso_4d.^2));

                app.s.dpar_4do_diff = app.s.d0_4d - (app.s.d0_4d - app.s.dpar_4d)./(1 + app.s.omega_v_diff.^2./app.s.rpar_4d.^2);
                app.s.dperp_4do_diff = app.s.d0_4d - (app.s.d0_4d - app.s.dperp_4d)./(1 + app.s.omega_v_diff.^2./app.s.rperp_4d.^2);
                diso_4d_diff = (app.s.dpar_4do_diff + 2*app.s.dperp_4do_diff)/3;
                daniso_4d_diff = (app.s.dpar_4do_diff - app.s.dperp_4do_diff)/3;
                sddelta_4d_diff = msf_notfinite2zero((daniso_4d_diff.^2)./(diso_4d_diff.^2));

                disoo_limited_4d = diso_4d>=(app.MinSelectedDiso.Value *1e-9) & diso_4d<=(app.MaxSelectedDiso.Value *1e-9);
                sddeltao_limited_4d = sddelta_4d>=(app.MinSelectedDdelta.Value) & sddelta_4d<=(app.MaxSelectedDdelta.Value);
                r1_limited_4d = (app.s.r1_4d)>=(app.MinSelectedR1.Value) & (app.s.r1_4d)<=(app.MaxSelectedR1.Value);
                r2_limited_4d = (app.s.r2_4d)>=(app.MinSelectedR2.Value) & (app.s.r2_4d)<=(app.MaxSelectedR2.Value);
                weights_limited_4d = (app.s.w_4d)>=(app.MinSelectedWeights.Value) & (app.s.w_4d)<=(app.MaxSelectedWeights.Value);
                if strcmp(app.SelectionDropDownFrqDiff.Value,'Diso')
                    Diff_4d = diso_4d-diso_4d_diff;
                    Diff_4d_limited = Diff_4d>=(app.MinSelectedFrqDiff.Value*1e-9) & Diff_4d<=(app.MaxSelectedFrqDiff.Value*1e-9);
                end
                if strcmp(app.SelectionDropDownFrqDiff.Value,'Ddelta')
                    Diff_4d = sddelta_4d-sddelta_4d_diff;
                    Diff_4d_limited = Diff_4d>=(app.MinSelectedFrqDiff.Value) & Diff_4d<=(app.MaxSelectedFrqDiff.Value);
                end

                % Sort components to be able to select them
                Ncomponents = 10;
                app.s.w_4d_sort = reshape(app.s.w_4d,size(app.s.w_4d,1),size(app.s.w_4d,2),size(app.s.w_4d,3),Ncomponents,size(app.s.w_4d,4)/Ncomponents);
                [~,sort_order_4d] = sort(app.s.w_4d_sort,4,'descend');
                sort_order_4d = reshape(sort_order_4d,size(sort_order_4d,1),size(sort_order_4d,2),size(sort_order_4d,3),size(sort_order_4d,4)*size(sort_order_4d,5));
                limited_comp_4d = ones(size(app.s.w_4d));
                for ind_comp =1:10
                    if eval(sprintf('app.CheckBoxComp%d.Value',ind_comp)) ==0
                        limited_comp_4d(sort_order_4d==ind_comp) =0;
                    end
                end
                mat_limited = logical(disoo_limited_4d.*sddeltao_limited_4d.*r1_limited_4d.*r2_limited_4d.*Diff_4d_limited.*weights_limited_4d.*limited_comp_4d);
            end

            % Display number of component
            %             one_mat = ones(size(app.s.w_4d));
            %             one_mat = one_mat.*logical(app.s.w_4d.*mat_limited);

            % Display the sum of the weight
            one_mat = app.s.w_4d.*mat_limited;

            map_limited = squeeze(sum(one_mat,4));
            app.s.Map_selected = map_limited;

            display_weight_map(app);
            display_2D_dist(app);

        end

        % Display weight maps
        function [] = display_weight_map(app)
            if app.s.dim3D == 1
                imagesc(app.UIAxesMapSelected,app.s.Map_selected(:,:,app.s.slice));
                app.UIAxesMapSelected.XLim =[1,size(app.s.Map_selected,2)]; app.UIAxesMapSelected.YLim = [1,size(app.s.Map_selected,1)];
            else
                imagesc(app.UIAxesMapSelected,app.s.Map_selected);
                app.UIAxesMapSelected.XLim =[1,size(app.s.Map_selected,2)]; app.UIAxesMapSelected.YLim = [1,size(app.s.Map_selected,1)];
            end
            colormap(app.UIAxesMapSelected,'gray');

        end

        % Display 2D distributions
        function [] = display_2D_dist(app)
            cla(app.UIAxesDist2D);cla(app.UIAxesProfDistX);cla(app.UIAxesProfDistY);
            if strcmp(app.s.method,'dtor1r2d')
                hold(app.UIAxesDist2D, 'on');
                hold(app.UIAxesProfDistX, 'on');
                hold(app.UIAxesProfDistY, 'on');
                NFreq = app.NfreqEditField.Value;

                freq_list = app.OmegafrqEditField.Value:(app.FrqdiffEditField.Value-app.OmegafrqEditField.Value)/(NFreq-1):app.FrqdiffEditField.Value;
                for ind_frq = 1:NFreq
                    frq_current_val =freq_list(ind_frq);
                    [~,dparo_tmp,dperpo_tmp] = my_dtor1r2d_dist2parso(app,frq_current_val);
                    [~,disoo_tmp,~,~,~,~,sddeltao_tmp] = my_dtd_pars2dpars(app,dparo_tmp,dperpo_tmp);

                    if strcmp(app.SelectionDropDownDistX.Value,'Ddelta')
                        x = sddeltao_tmp(app.s.vect_limited);
                        axpars.xmin = min(app.s.edgesDdelta)-0.1; axpars.xmax = max(app.s.edgesDdelta)+0.1;
                        app.UIAxesDist2D.XLabel.String = 'Ddelta';
                    end
                    if strcmp(app.SelectionDropDownDistX.Value,'Diso')
                        x = disoo_tmp(app.s.vect_limited)./1e-9;
                        axpars.xmin = min(app.s.edgesDiso)-0.1; axpars.xmax = max(app.s.edgesDiso)+0.1;
                        app.UIAxesDist2D.XLabel.String = 'Diso';
                    end
                    if strcmp(app.SelectionDropDownDistX.Value,'R1')
                        x = app.s.r1(app.s.vect_limited);
                        axpars.xmin = min(app.s.edgesR1)-1; axpars.xmax = max(app.s.edgesR1)+1;
                        app.UIAxesDist2D.XLabel.String = 'R1';
                    end
                    if strcmp(app.SelectionDropDownDistX.Value,'R2')
                        x = app.s.r2(app.s.vect_limited);
                        axpars.xmin = min(app.s.edgesR2)-5; axpars.xmax = max(app.s.edgesR2)+5;
                        app.UIAxesDist2D.XLabel.String = 'R2';
                    end
                    if strcmp(app.SelectionDropDownDistX.Value,'Freq')
                        if strcmp(app.SelectionDropDownFrqDiff.Value,'Diso')
                            x = (app.s.disoo(app.s.vect_limited)-disoo_tmp(app.s.vect_limited))./1e-9;
                        end
                        if strcmp(app.SelectionDropDownFrqDiff.Value,'Ddelta')
                            x = app.s.sddeltao(app.s.vect_limited)-sddeltao_tmp(app.s.vect_limited);
                        end
                        axpars.xmin = min(app.s.edgesFrqDiff); axpars.xmax = max(app.s.edgesFrqDiff);
                        app.UIAxesDist2D.XLabel.String = 'Freq diff';
                    end

                    if strcmp(app.SelectionDropDownDistY.Value,'Ddelta')
                        y = sddeltao_tmp(app.s.vect_limited);
                        axpars.ymin = min(app.s.edgesDdelta)-0.1; axpars.ymax = max(app.s.edgesDdelta)+0.1;
                        app.UIAxesDist2D.YLabel.String = 'Ddelta';
                    end
                    if strcmp(app.SelectionDropDownDistY.Value,'Diso')
                        y = disoo_tmp(app.s.vect_limited);
                        axpars.ymin = min(app.s.edgesDiso)-0.1; axpars.ymax = max(app.s.edgesDiso)+0.1;
                        app.UIAxesDist2D.YLabel.String = 'Diso';
                    end
                    if strcmp(app.SelectionDropDownDistY.Value,'R1')
                        y = app.s.r1(app.s.vect_limited);
                        axpars.ymin = min(app.s.edgesR1)-1; axpars.ymax = max(app.s.edgesR1)+1;
                        app.UIAxesDist2D.YLabel.String = 'R1';
                    end
                    if strcmp(app.SelectionDropDownDistY.Value,'R2')
                        y = app.s.r2(app.s.vect_limited);
                        axpars.ymin = min(app.s.edgesR2)-5; axpars.ymax = max(app.s.edgesR2)+5;
                        app.UIAxesDist2D.YLabel.String = 'R2';
                    end
                    if strcmp(app.SelectionDropDownDistY.Value,'Freq')
                        if strcmp(app.SelectionDropDownFrqDiff.Value,'Diso')
                            y = (app.s.disoo(app.s.vect_limited)-disoo_tmp(app.s.vect_limited))./1e-9;
                        end
                        if strcmp(app.SelectionDropDownFrqDiff.Value,'Ddelta')
                            y = app.s.sddeltao(app.s.vect_limited)-sddeltao_tmp(app.s.vect_limited);
                        end
                        axpars.ymin = min(app.s.edgesFrqDiff); axpars.ymax = max(app.s.edgesFrqDiff);
                        app.UIAxesDist2D.YLabel.String = 'Freq diff';
                    end

                    %% Display 2D
                    contourpars.Nx = app.s.Nbins; contourpars.Ny = app.s.Nbins; contourpars.Nlevels = 3;
                    x_coord = linspace(axpars.xmin,axpars.xmax,contourpars.Nx)';
                    y_coord = linspace(axpars.ymin,axpars.ymax,contourpars.Ny)';
                    my_sigma = app.SigmaEditField.Value;
                    xsigma = single(my_sigma*abs(x_coord(2) - x_coord(1)));
                    ysigma = single(my_sigma*abs(y_coord(2) - y_coord(1)));
                    nx = numel(x_coord);
                    ny = numel(y_coord);
                    [xx,yy] = ndgrid(x_coord,y_coord);
                    w0 = app.s.w(app.s.vect_limited);
                    n0 = numel(w0);
                    xx = single(xx); yy = single(yy);n0 = single(n0);
                    nx = single(nx);ny = single(ny);
                    %% Low memory option
                    x_dw = abs(x_coord(1)-x_coord(2));
                    y_dw = abs(y_coord(1)-y_coord(2));
                    w0(x>max(x_coord+x_dw./2))=0; w0(x<min(x_coord-x_dw./2))=0;
                    w0(y>max(y_coord+y_dw./2))=0; w0(y<min(y_coord-y_dw./2))=0;
                    w0_2D = zeros(size(x_coord,1),size(y_coord,1));
                    for ind = 1:n0
                        [~,ind_x] = min(abs(x_coord-x(ind,1)));
                        [~,ind_y] = min(abs(y_coord-y(ind,1)));
                        w0_2D(ind_x,ind_y) = w0_2D(ind_x,ind_y) + w0(ind,1);
                    end
                    dist_s.w=imgaussfilt(w0_2D,[my_sigma my_sigma]);
                    %% End of Low memory option
                    %                     xx_k = repmat(xx(:),[1 n0]);
                    %                     yy_k = repmat(yy(:),[1 n0]);
                    %                     x0_k = repmat(x',[nx*ny 1]);
                    %                     y0_k = repmat(y',[nx*ny 1]);
                    %                     k = 1/(xsigma*sqrt(2*pi)).*exp(-(xx_k-x0_k).^2/(2*xsigma^2)).*...
                    %                         1/(ysigma*sqrt(2*pi)).*exp(-(yy_k-y0_k).^2/(2*ysigma^2));
                    %
                    %                     p = k*w0;
                    %
                    %                     dist_s.w = reshape(p,[nx ny]);
                    wprojx = sum(dist_s.w,2);
                    wprojy = sum(dist_s.w,1);

                    col = .8*freq_list(ind_frq)/max(freq_list) + [0 0 0];
                    contour(app.UIAxesDist2D,x_coord,y_coord,dist_s.w',contourpars.Nlevels,'LineWidth',1,'Color',col);
                    app.UIAxesDist2D.XLim =[axpars.xmin axpars.xmax]; app.UIAxesDist2D.YLim = [axpars.ymin axpars.ymax];

                    %%% Mod Maxime 2022: projection normalization
                    Norm_fact = sum(wprojx);
                    wprojx = wprojx./Norm_fact;
                    wprojy = wprojy./Norm_fact;
                    wprojx(isnan(wprojx)) =0.0000001;wprojy(isnan(wprojy)) =0.0000001;
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                    plot(app.UIAxesProfDistX,x_coord,wprojx,'Color',col);
                    plot(app.UIAxesProfDistY,y_coord,wprojy,'Color',col);
                    app.UIAxesProfDistX.XLim =[axpars.xmin axpars.xmax]; app.UIAxesProfDistX.YLim = max(abs(wprojx))*[-.1 1.5];
                    app.UIAxesProfDistY.XLim =[axpars.ymin axpars.ymax]; app.UIAxesProfDistY.YLim = max(abs(wprojy))*[-.1 1.5];
                end


            end

            if strcmp(app.s.method,'dtr1r2d')
                if strcmp(app.SelectionDropDownDistX.Value,'Ddelta')
                    x = app.s.sddeltao(app.s.vect_limited);
                    axpars.xmin = min(app.s.edgesDdelta)-0.1; axpars.xmax = max(app.s.edgesDdelta)+0.1;
                    app.UIAxesDist2D.XLabel.String = 'Ddelta';
                end
                if strcmp(app.SelectionDropDownDistX.Value,'Diso')
                    x = app.s.disoo(app.s.vect_limited)./1e-9;
                    axpars.xmin = min(app.s.edgesDiso)-0.1; axpars.xmax = max(app.s.edgesDiso)+0.1;
                    app.UIAxesDist2D.XLabel.String = 'Diso';
                end
                if strcmp(app.SelectionDropDownDistX.Value,'R1')
                    x = app.s.r1(app.s.vect_limited);
                    axpars.xmin = min(app.s.edgesR1)-1; axpars.xmax = max(app.s.edgesR1)+1;
                    app.UIAxesDist2D.XLabel.String = 'R1';
                end
                if strcmp(app.SelectionDropDownDistX.Value,'R2')
                    x = app.s.r2(app.s.vect_limited);
                    axpars.xmin = min(app.s.edgesR2)-5; axpars.xmax = max(app.s.edgesR2)+5;
                    app.UIAxesDist2D.XLabel.String = 'R2';
                end
                if strcmp(app.SelectionDropDownDistY.Value,'Ddelta')
                    y = app.s.sddeltao(app.s.vect_limited);
                    axpars.ymin = min(app.s.edgesDdelta)-0.1; axpars.ymax = max(app.s.edgesDdelta)+0.1;
                    app.UIAxesDist2D.YLabel.String = 'Ddelta';
                end
                if strcmp(app.SelectionDropDownDistY.Value,'Diso')
                    y = app.s.disoo(app.s.vect_limited);
                    axpars.ymin = min(app.s.edgesDiso)-0.1; axpars.ymax = max(app.s.edgesDiso)+0.1;
                    app.UIAxesDist2D.YLabel.String = 'Diso';
                end
                if strcmp(app.SelectionDropDownDistY.Value,'R1')
                    y = app.s.r1(app.s.vect_limited);
                    axpars.ymin = min(app.s.edgesR1)-1; axpars.ymax = max(app.s.edgesR1)+1;
                    app.UIAxesDist2D.YLabel.String = 'R1';
                end
                if strcmp(app.SelectionDropDownDistY.Value,'R2')
                    y = app.s.r2(app.s.vect_limited);
                    axpars.ymin = min(app.s.edgesR2)-5; axpars.ymax = max(app.s.edgesR2)+5;
                    app.UIAxesDist2D.YLabel.String = 'R2';
                end

                %% Display
                contourpars.Nx = app.s.Nbins; contourpars.Ny = app.s.Nbins; contourpars.Nlevels = 3;
                x_coord = linspace(axpars.xmin,axpars.xmax,contourpars.Nx)';
                y_coord = linspace(axpars.ymin,axpars.ymax,contourpars.Ny)';
                xsigma = my_sigma*abs(x_coord(2) - x_coord(1));
                ysigma = my_sigma*abs(y_coord(2) - y_coord(1));
                nx = numel(x_coord);
                ny = numel(y_coord);
                [xx,yy] = ndgrid(x_coord,y_coord);
                w0 = app.s.w(app.s.vect_limited);
                n0 = numel(w0);
                %% Low memory option
                x_dw = abs(x_coord(1)-x_coord(2));
                y_dw = abs(y_coord(1)-y_coord(2));
                w0(x>max(x_coord+x_dw./2))=0; w0(x<min(x_coord-x_dw./2))=0;
                w0(y>max(y_coord+y_dw./2))=0; w0(y<min(y_coord-y_dw./2))=0;
                w0_2D = zeros(size(x_coord,1),size(y_coord,1));
                for ind = 1:n0
                    [~,ind_x] = min(abs(x_coord-x(ind,1)));
                    [~,ind_y] = min(abs(y_coord-y(ind,1)));
                    w0_2D(ind_x,ind_y) = w0_2D(ind_x,ind_y) + w0(ind,1);
                end
                dist_s.w=imgaussfilt(w0_2D,[my_sigma my_sigma]);
                %% End of Low memory option
                %% Original
                %                 xx_k = repmat(xx(:),[1 n0]);
                %                 yy_k = repmat(yy(:),[1 n0]);
                %                 x0_k = repmat(x',[nx*ny 1]);
                %                 y0_k = repmat(y',[nx*ny 1]);
                %                 k = 1/(xsigma*sqrt(2*pi)).*exp(-(xx_k-x0_k).^2/(2*xsigma^2)).*...
                %                     1/(ysigma*sqrt(2*pi)).*exp(-(yy_k-y0_k).^2/(2*ysigma^2));
                %
                %                 p = k*w0;
                %% End of Original

                dist_s.w = reshape(p,[nx ny]);
                wprojx = sum(dist_s.w,2);
                wprojy = sum(dist_s.w,1);

                contour(app.UIAxesDist2D,x_coord,y_coord,dist_s.w',contourpars.Nlevels,'LineWidth',1,'Color',0.5*[1 1 1]);
                app.UIAxesDist2D.XLim =[axpars.xmin axpars.xmax]; app.UIAxesDist2D.YLim = [axpars.ymin axpars.ymax];

                %%% Mod Maxime 2022: projection normalization
                Norm_fact = sum(wprojx);
                wprojx = wprojx./Norm_fact;
                wprojy = wprojy./Norm_fact;
                wprojx(isnan(wprojx)) =0.0000001;wprojy(isnan(wprojy)) =0.0000001;
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                plot(app.UIAxesProfDistX,x_coord,wprojx,'-');
                plot(app.UIAxesProfDistY,y_coord,wprojy,'-');
                app.UIAxesProfDistX.XLim =[axpars.xmin axpars.xmax]; app.UIAxesProfDistX.YLim = max(abs(wprojx))*[-.1 1.5];
                app.UIAxesProfDistY.XLim =[axpars.ymin axpars.ymax]; app.UIAxesProfDistY.YLim = max(abs(wprojy))*[-.1 1.5];
            end

        end
        %%%%%%%   DTD functions  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function [dpar,dperp,theta,phi,d0,rpar,rperp,r1,r2,w] = my_dtor1r2d_bsmerge_initializepars(~,size_bsn,Nbsn)
            dpar = single(zeros(size_bsn(1,1),size_bsn(1,2),size_bsn(1,3),size_bsn(1,4)*Nbsn));
            dperp = single(zeros(size_bsn(1,1),size_bsn(1,2),size_bsn(1,3),size_bsn(1,4)*Nbsn));
            theta = single(zeros(size_bsn(1,1),size_bsn(1,2),size_bsn(1,3),size_bsn(1,4)*Nbsn));
            phi = single(zeros(size_bsn(1,1),size_bsn(1,2),size_bsn(1,3),size_bsn(1,4)*Nbsn));
            d0 = single(zeros(size_bsn(1,1),size_bsn(1,2),size_bsn(1,3),size_bsn(1,4)*Nbsn));
            rpar = single(zeros(size_bsn(1,1),size_bsn(1,2),size_bsn(1,3),size_bsn(1,4)*Nbsn));
            rperp = single(zeros(size_bsn(1,1),size_bsn(1,2),size_bsn(1,3),size_bsn(1,4)*Nbsn));
            r1 = single(zeros(size_bsn(1,1),size_bsn(1,2),size_bsn(1,3),size_bsn(1,4)*Nbsn));
            r2 = single(zeros(size_bsn(1,1),size_bsn(1,2),size_bsn(1,3),size_bsn(1,4)*Nbsn));
            w = single(zeros(size_bsn(1,1),size_bsn(1,2),size_bsn(1,3),size_bsn(1,4)*Nbsn));
        end

        function [dpar,dperp,theta,phi,r1,r2,w] = my_dtr1r2d_bsmerge_initializepars(~,size_bsn,Nbsn)
            dpar = single(zeros(size_bsn(1,1),size_bsn(1,2),size_bsn(1,3),size_bsn(1,4)*Nbsn));
            dperp = single(zeros(size_bsn(1,1),size_bsn(1,2),size_bsn(1,3),size_bsn(1,4)*Nbsn));
            theta = single(zeros(size_bsn(1,1),size_bsn(1,2),size_bsn(1,3),size_bsn(1,4)*Nbsn));
            phi = single(zeros(size_bsn(1,1),size_bsn(1,2),size_bsn(1,3),size_bsn(1,4)*Nbsn));
            r1 = single(zeros(size_bsn(1,1),size_bsn(1,2),size_bsn(1,3),size_bsn(1,4)*Nbsn));
            r2 = single(zeros(size_bsn(1,1),size_bsn(1,2),size_bsn(1,3),size_bsn(1,4)*Nbsn));
            w = single(zeros(size_bsn(1,1),size_bsn(1,2),size_bsn(1,3),size_bsn(1,4)*Nbsn));
        end

        function [par,perp, theta, phi, r1, r2, w, n] = my_dtr1r2d_par2dist(~,ni,nj,nk,par,perp,theta,phi,r1,r2,w)
            if numel(ni) ==1
                par = squeeze(par(ni,nj,nk,:)); perp = squeeze(perp(ni,nj,nk,:));
                theta = squeeze(theta(ni,nj,nk,:)); phi = squeeze(phi(ni,nj,nk,:));
                r1 = squeeze(r1(ni,nj,nk,:));r2 = squeeze(r2(ni,nj,nk,:));
                w = squeeze(w(ni,nj,nk,:));
                n = numel(par);
            else
                par_tmp = zeros(numel(ni),1,size(par,4));perp_tmp = zeros(numel(ni),1,size(par,4));
                theta_tmp = zeros(numel(ni),1,size(par,4));phi_tmp = zeros(numel(ni),1,size(par,4));
                r1_tmp = zeros(numel(ni),1,size(par,4));r2_tmp = zeros(numel(ni),1,size(par,4));
                w_tmp = zeros(numel(ni),1,size(par,4));
                for ind=1:numel(ni)
                    par_tmp(ind,1,:) = par(ni(ind),nj(ind),nk,:);perp_tmp(ind,1,:) = perp(ni(ind),nj(ind),nk,:);
                    theta_tmp(ind,1,:) = theta(ni(ind),nj(ind),nk,:);phi_tmp(ind,1,:) = phi(ni(ind),nj(ind),nk,:);
                    r1_tmp(ind,1,:) = r1(ni(ind),nj(ind),nk,:);r2_tmp(ind,1,:) = r2(ni(ind),nj(ind),nk,:);
                    w_tmp(ind,1,:) = w(ni(ind),nj(ind),nk,:);
                end
                par = single(squeeze(permute(par_tmp,[3 1 2]))); perp = single(squeeze(permute(perp_tmp,[3 1 2])));
                theta = single(squeeze(permute(theta_tmp,[3 1 2]))); phi = single(squeeze(permute(phi_tmp,[3 1 2])));
                r1 = single(squeeze(permute(r1_tmp,[3 1 2]))); r2 = single(squeeze(permute(r2_tmp,[3 1 2])));
                w = single(squeeze(permute(w_tmp,[3 1 2])));
                n = single(numel(par));
            end
        end

        function [par,perp, theta, phi, d0, rpar, rperp, r1, r2, w, n] = my_dtor1r2d_par2dist(~,ni,nj,nk,par,perp,theta,phi,d0,rpar,rperp,r1,r2,w)
            if numel(ni) ==1
                par = squeeze(par(ni,nj,nk,:)); perp = squeeze(perp(ni,nj,nk,:));
                theta = squeeze(theta(ni,nj,nk,:)); phi = squeeze(phi(ni,nj,nk,:));
                d0 = squeeze(d0(ni,nj,nk,:));rpar = squeeze(rpar(ni,nj,nk,:));rperp = squeeze(rperp(ni,nj,nk,:));
                r1 = squeeze(r1(ni,nj,nk,:));r2 = squeeze(r2(ni,nj,nk,:));
                w = squeeze(w(ni,nj,nk,:));
                n = single(numel(par));
            else
                par_tmp = zeros(numel(ni),1,size(par,4));perp_tmp = zeros(numel(ni),1,size(par,4));
                theta_tmp = zeros(numel(ni),1,size(par,4));phi_tmp = zeros(numel(ni),1,size(par,4));
                d0_tmp = zeros(numel(ni),1,size(par,4));rpar_tmp = zeros(numel(ni),1,size(par,4));rperp_tmp = zeros(numel(ni),1,size(par,4));
                r1_tmp = zeros(numel(ni),1,size(par,4));r2_tmp = zeros(numel(ni),1,size(par,4));
                w_tmp = zeros(numel(ni),1,size(par,4));
                for ind=1:numel(ni)
                    par_tmp(ind,1,:) = par(ni(ind),nj(ind),nk,:);perp_tmp(ind,1,:) = perp(ni(ind),nj(ind),nk,:);
                    theta_tmp(ind,1,:) = theta(ni(ind),nj(ind),nk,:);phi_tmp(ind,1,:) = phi(ni(ind),nj(ind),nk,:);
                    d0_tmp(ind,1,:) = d0(ni(ind),nj(ind),nk,:);rpar_tmp(ind,1,:) = rpar(ni(ind),nj(ind),nk,:);rperp_tmp(ind,1,:) = rperp(ni(ind),nj(ind),nk,:);
                    r1_tmp(ind,1,:) = r1(ni(ind),nj(ind),nk,:);r2_tmp(ind,1,:) = r2(ni(ind),nj(ind),nk,:);
                    w_tmp(ind,1,:) = w(ni(ind),nj(ind),nk,:);
                end
                par = single(squeeze(permute(par_tmp,[3 1 2]))); perp = single(squeeze(permute(perp_tmp,[3 1 2])));
                theta = single(squeeze(permute(theta_tmp,[3 1 2]))); phi = single(squeeze(permute(phi_tmp,[3 1 2])));
                d0 = single(squeeze(permute(d0_tmp,[3 1 2]))); rpar = single(squeeze(permute(rpar_tmp,[3 1 2]))); rperp = single(squeeze(permute(rperp_tmp,[3 1 2])));
                r1 = single(squeeze(permute(r1_tmp,[3 1 2]))); r2 = single(squeeze(permute(r2_tmp,[3 1 2])));
                w = single(squeeze(permute(w_tmp,[3 1 2])));
                n = single(numel(par));
            end
        end

        function [app,dparo,dperpo] = my_dtor1r2d_dist2parso(app,omega_v)
            omega_v = omega_v.*(2*pi);
            N_point_selected = size(app.s.coord_point,1);
            omega_vect = repmat(omega_v,app.s.nComp/N_point_selected,N_point_selected);
            dparo = single(app.s.d0 - (app.s.d0 - app.s.par)./(1 + omega_vect.^2./app.s.rpar.^2));
            dperpo = single(app.s.d0 - (app.s.d0 - app.s.perp)./(1 + omega_vect.^2./app.s.rperp.^2));
        end

        function [app,disoo,danisoo,dratioo,ddeltao,sdanisoo,sddeltao] = my_dtd_pars2dpars(app,dparo,dperpo)
            disoo = single((dparo + 2*dperpo)/3);
            danisoo = single((dparo - dperpo)/3);
            dratioo = single(msf_notfinite2zero(dparo./dperpo));
            ddeltao = single(msf_notfinite2zero(danisoo./disoo));
            sdanisoo = single(danisoo.^2);
            sddeltao = single(msf_notfinite2zero(sdanisoo./disoo.^2));

        end
        %%%%%%%  End of  DTD functions  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        function [bar_hist] = dist2bar(~,edges_hist,parameter_data,weights_data)
            bar_hist = zeros(size(edges_hist));
            for ind_hist = 1:numel(bar_hist)-1
                bar_hist(1,ind_hist) = sum(weights_data(parameter_data >= edges_hist(1,ind_hist) & parameter_data < edges_hist(1,ind_hist+1)));
            end
        end
    end

    methods (Access = public)

        function MoveRoi(app,~,~)
            if strcmp(app.SelectionDropDown.Value,'Point')
                app.s.coord_point = round(app.s.roi.Position);
                app.LabelNI.Text = num2str(app.s.coord_point(1,1));
                app.LabelNJ.Text = num2str(app.s.coord_point(1,2));
            else
                list_coord_point = find(createMask(app.s.roi));
                [row,col] = ind2sub(size(squeeze(app.s.pmaps.mdiso(:,:,1))),list_coord_point);
                app.s.coord_point = [col,row];
                app.LabelNI.Text = '';
                app.LabelNJ.Text = '';
            end
            display_hist(app);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Loadpdata_mddButton
        function Loadpdata_mddButtonPushed(app, event)
            if ~isfield(app.s,'path_mdd')
                app.s.path_mdd = uigetdir;
            end
            app.s.method = app.MethodDropDown.Value;
            app.s.nii_path = [app.s.path_mdd filesep 'nii_xps'];
            app.s.bs_path = [app.s.path_mdd filesep app.s.method filesep 'bootstraps'];

            if strcmp(app.s.method,'dtr1r2d')
                app.OmegafrqEditField.Visible = 'off';
                app.OmegafrqEditFieldLabel.Visible = 'off';
                app.SelectionDropDownFrqDiff.Visible = 'off';
                app.SelectionDropDown_2Label.Visible = 'off';
                app.FrqdiffEditField.Visible = 'off';
                app.FrqdiffEditFieldLabel.Visible = 'off';
                app.UIAxesFrqDiff.Visible = 'off';
                app.MinSelectedFrqDiff.Visible = 'off';
                app.MinSelectedEditFieldLabel_5.Visible = 'off';
                app.MaxSelectedFrqDiff.Visible = 'off';
                app.MaxSelectedEditFieldLabel_5.Visible = 'off';
            end

            if strcmp(app.s.method,'dtor1r2d')
                app.OmegafrqEditField.Visible = 'on';
                app.OmegafrqEditFieldLabel.Visible = 'on';
                app.SelectionDropDownFrqDiff.Visible = 'on';
                app.SelectionDropDown_2Label.Visible = 'on';
                app.FrqdiffEditField.Visible = 'on';
                app.FrqdiffEditFieldLabel.Visible = 'on';
                app.UIAxesFrqDiff.Visible = 'on';
                app.MinSelectedFrqDiff.Visible = 'on';
                app.MinSelectedEditFieldLabel_5.Visible = 'on';
                app.MaxSelectedFrqDiff.Visible = 'on';
                app.MaxSelectedEditFieldLabel_5.Visible = 'on';

                if strcmp(app.SelectionDropDownFrqDiff.Value,'Diso')
                    app.MinSelectedFrqDiff.Value = -2.5;
                    app.MaxSelectedFrqDiff.Value = 2.5;
                end
                if strcmp(app.SelectionDropDownFrqDiff.Value,'Ddelta')
                    app.MinSelectedFrqDiff.Value = -1;
                    app.MaxSelectedFrqDiff.Value = 1;
                end
            end

            msg_wait = msgbox('Loading the data...');

            % Load xps
            app.s.xps = mdm_xps_load(fullfile(app.s.nii_path, 'data_xps.mat'));

            % Load Bootstrap
            bsno = msf_getdirno(app.s.bs_path);
            for nbs = 1:numel(bsno)
                mfs_fn   = fullfile(app.s.bs_path,num2str(bsno(nbs)),'mfs.mat');
                if exist(mfs_fn,'file')==2
                    mfs = mdm_mfs_load(mfs_fn);
                    if nbs ==1
                        size_bsn = size(mfs.m);
                        if strcmp(app.s.method,'dtor1r2d')
                            size_bsn(1,4) = (size_bsn(1,4)-1)/10;
                            [app.s.dpar_4d,app.s.dperp_4d,app.s.theta_4d,app.s.phi_4d,app.s.d0_4d,app.s.rpar_4d,app.s.rperp_4d,app.s.r1_4d,app.s.r2_4d,app.s.w_4d] = my_dtor1r2d_bsmerge_initializepars(app,size_bsn,numel(bsno));
                        end
                        if strcmp(app.s.method,'dtr1r2d')
                            size_bsn(1,4) = (size_bsn(1,4)-1)/7;
                            [app.s.dpar_4d,app.s.dperp_4d,app.s.theta_4d,app.s.phi_4d,app.s.r1_4d,app.s.r2_4d,app.s.w_4d] = my_dtr1r2d_bsmerge_initializepars(app,size_bsn,numel(bsno));
                        end
                    end
                    if strcmp(app.s.method,'dtor1r2d')
                        [dpar_temp,dperp_temp,theta_temp,phi_temp,d0_temp,rpar_temp,rperp_temp,r1_temp,r2_temp,w_temp] = dtor1r2d_4d_m2pars(mfs.m);
                        app.s.dpar_4d(:,:,:,1+(nbs-1)*size_bsn(1,4):nbs*size_bsn(1,4)) = single(dpar_temp);
                        app.s.dperp_4d(:,:,:,1+(nbs-1)*size_bsn(1,4):nbs*size_bsn(1,4)) = single(dperp_temp);
                        app.s.theta_4d(:,:,:,1+(nbs-1)*size_bsn(1,4):nbs*size_bsn(1,4)) = single(theta_temp);
                        app.s.phi_4d(:,:,:,1+(nbs-1)*size_bsn(1,4):nbs*size_bsn(1,4)) = single(phi_temp);
                        app.s.d0_4d(:,:,:,1+(nbs-1)*size_bsn(1,4):nbs*size_bsn(1,4)) = single(d0_temp);
                        app.s.rpar_4d(:,:,:,1+(nbs-1)*size_bsn(1,4):nbs*size_bsn(1,4)) = single(rpar_temp);
                        app.s.rperp_4d(:,:,:,1+(nbs-1)*size_bsn(1,4):nbs*size_bsn(1,4)) = single(rperp_temp);
                        app.s.r1_4d(:,:,:,1+(nbs-1)*size_bsn(1,4):nbs*size_bsn(1,4)) = single(r1_temp);
                        app.s.r2_4d(:,:,:,1+(nbs-1)*size_bsn(1,4):nbs*size_bsn(1,4)) = single(r2_temp);
                        app.s.w_4d(:,:,:,1+(nbs-1)*size_bsn(1,4):nbs*size_bsn(1,4)) = single(w_temp);
                    end
                    if strcmp(app.s.method,'dtr1r2d')
                        [dpar_temp,dperp_temp,theta_temp,phi_temp,r1_temp,r2_temp,w_temp] = dtr1r2d_4d_m2pars(mfs.m);
                        app.s.dpar_4d(:,:,:,1+(nbs-1)*size_bsn(1,4):nbs*size_bsn(1,4)) = single(dpar_temp);
                        app.s.dperp_4d(:,:,:,1+(nbs-1)*size_bsn(1,4):nbs*size_bsn(1,4)) = single(dperp_temp);
                        app.s.theta_4d(:,:,:,1+(nbs-1)*size_bsn(1,4):nbs*size_bsn(1,4)) = single(theta_temp);
                        app.s.phi_4d(:,:,:,1+(nbs-1)*size_bsn(1,4):nbs*size_bsn(1,4)) = single(phi_temp);
                        app.s.r1_4d(:,:,:,1+(nbs-1)*size_bsn(1,4):nbs*size_bsn(1,4)) = single(r1_temp);
                        app.s.r2_4d(:,:,:,1+(nbs-1)*size_bsn(1,4):nbs*size_bsn(1,4)) = single(r2_temp);
                        app.s.w_4d(:,:,:,1+(nbs-1)*size_bsn(1,4):nbs*size_bsn(1,4)) = single(w_temp);
                    end
                end
            end
            % Load opt
            app.s.opt = load([app.s.bs_path filesep 'opt.mat']);

            % Load maps
            app.s.map_nams = {'mdiso';'fractions'};
            app.s.Nmaps = numel(app.s.map_nams);
            for nmap = 1:app.s.Nmaps
                map_nam = app.s.map_nams{nmap};
                pmap_fn = fullfile(app.s.path_mdd,'pmaps',[app.s.method '_' map_nam '.nii.gz']);
                [I_tmp,~]  = mdm_nii_read(pmap_fn);
                sz = size(I_tmp);
                if sz(1) == 3
                    if length(sz) == 3
                        I_tmp = reshape(I_tmp,[3 sz(2) sz(3) 1]);
                    end
                else
                    if length(sz) == 2
                        I_tmp = reshape(I_tmp,[sz(1) sz(2) 1]);
                    end
                end
                app.s.pmaps.(map_nam) = double(I_tmp);
            end

            if size(app.s.dpar_4d,3) ~=1
                if app.ReverseorderCheckBox.Value ==1
                    for nmap = 1:app.s.Nmaps
                        map_nam = app.s.map_nams{nmap};
                        if size(app.s.pmaps.(map_nam),1)~=3
                            app.s.pmaps.(map_nam) = flip(app.s.pmaps.(map_nam),3);
                        else
                            app.s.pmaps.(map_nam) = flip(app.s.pmaps.(map_nam),4);
                        end
                    end
                end
            end

            % close msgbox
            close(msg_wait)

            % Adjust items lists
            if strcmp(app.s.method,'dtr1r2d')
                app.SelectionDropDownDistX.Items = {'Diso','Ddelta','R1','R2'};
                app.SelectionDropDownDistY.Items = {'Diso','Ddelta','R1','R2'};
            end

            if strcmp(app.s.method,'dtor1r2d')
                app.SelectionDropDownDistX.Items = {'Diso','Ddelta','R1','R2','Freq'};
                app.SelectionDropDownDistY.Items = {'Diso','Ddelta','R1','R2','Freq'};
            end

            % Initialyse Weights maximum
            app.MaxSelectedWeights.Value = double(ceil(max(app.s.w_4d(:))));

            % Initialyse Parameters maximum
            app.MaxSelectedR1.Value = app.s.opt.opt.dtor1r2d.r1max;
            app.MaxSelectedR2.Value = app.s.opt.opt.dtor1r2d.r2max;


            % Check dimension
            if size(app.s.dpar_4d,3) ~=1
                app.s.dim3D = 1;
            else
                app.s.dim3D = 0;
            end
            app.s.MatrixSize = size(app.s.dpar_4d);
            app.s.MatrixSize = app.s.MatrixSize(1:3);
            if app.s.dim3D == 1
                app.s.slice = round(size(app.s.dpar_4d,3)/2);
                app.SliceSlider.Limits = [1, size(app.s.dpar_4d,3)];
                app.SliceSlider.Value = app.s.slice;

                app.SliceSlider.Visible = 'on';
                app.SliceSliderLabel.Visible = 'on';

                app.ReverseorderCheckBox.Visible = 'on';
            else
                app.s.slice = 1;
                app.SliceSlider.Limits = [1,2];

                app.SliceSlider.Visible = 'off';
                app.SliceSliderLabel.Visible = 'off';

                app.ReverseorderCheckBox.Visible = 'off';
            end

            % Display map
            display_map(app);
            %
        end

        % Value changed function: MapDropDown
        function MapDropDownValueChanged(app, event)
            display_map(app);
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            if strcmp(app.SelectionDropDown.Value,'Point')
                app.s.roi = drawpoint(app.UIAxesMap);
                addlistener(app.s.roi,'ROIMoved',@(src, event)MoveRoi(app));
                [app.s.coord_point] = round(app.s.roi.Position);
                app.LabelNI.Text = num2str(app.s.coord_point(1,1));
                app.LabelNJ.Text = num2str(app.s.coord_point(1,2));
            end

            if strcmp(app.SelectionDropDown.Value,'ROI')
                app.s.roi = drawfreehand(app.UIAxesMap);
                addlistener(app.s.roi,'ROIMoved',@(src, event)MoveRoi(app));
                list_coord_point = find(createMask(app.s.roi));
                [row,col] = ind2sub(size(squeeze(app.s.pmaps.mdiso(:,:,1))),list_coord_point);
                app.s.coord_point = [col,row];
                app.LabelNI.Text = '';
                app.LabelNJ.Text = '';
            end

            display_hist(app);
        end

        % Value changed function: NbinhistEditField
        function NbinhistEditFieldValueChanged(app, event)
            display_hist(app);
        end

        % Value changed function: OmegafrqEditField
        function OmegafrqEditFieldValueChanged(app, event)
            display_hist(app);
        end

        % Value changed function: MinSelectedDiso
        function MinSelectedDisoValueChanged(app, event)
            if app.MinSelectedDiso.Value >= app.MaxSelectedDiso.Value
                app.MaxSelectedDiso.Value = app.MinSelectedDiso.Value +0.1;
            end
            display_hist(app);
        end

        % Value changed function: MaxSelectedDiso
        function MaxSelectedDisoValueChanged(app, event)
            if app.MaxSelectedDiso.Value <= app.MinSelectedDiso.Value
                app.MinSelectedDiso.Value = app.MaxSelectedDiso.Value -0.1;
            end
            display_hist(app);
        end

        % Value changed function: MinSelectedDdelta
        function MinSelectedDdeltaValueChanged(app, event)
            if app.MinSelectedDdelta.Value >= app.MaxSelectedDdelta.Value
                app.MaxSelectedDdelta.Value = app.MinSelectedDdelta.Value +0.1;
            end
            display_hist(app);
        end

        % Value changed function: MaxSelectedDdelta
        function MaxSelectedDdeltaValueChanged(app, event)
            if app.MaxSelectedDdelta.Value <= app.MinSelectedDdelta.Value
                app.MinSelectedDdelta.Value = app.MaxSelectedDdelta.Value -0.1;
            end
            display_hist(app);
        end

        % Value changed function: MinSelectedR1
        function MinSelectedR1ValueChanged(app, event)
            if app.MinSelectedR1.Value >= app.MaxSelectedR1.Value
                app.MaxSelectedR1.Value = app.MinSelectedR1.Value +1;
            end
            display_hist(app);
        end

        % Value changed function: MaxSelectedR1
        function MaxSelectedR1ValueChanged(app, event)
            if app.MaxSelectedR1.Value <= app.MinSelectedR1.Value
                app.MinSelectedR1.Value = app.MaxSelectedR1.Value -1;
            end
            display_hist(app);
        end

        % Value changed function: MinSelectedR2
        function MinSelectedR2ValueChanged(app, event)
            if app.MinSelectedR2.Value >= app.MaxSelectedR2.Value
                app.MaxSelectedR2.Value = app.MinSelectedR2.Value +1;
            end
            display_hist(app);
        end

        % Value changed function: MaxSelectedR2
        function MaxSelectedR2ValueChanged(app, event)
            if app.MaxSelectedR2.Value <= app.MinSelectedR2.Value
                app.MinSelectedR2.Value = app.MaxSelectedR2.Value -1;
            end
            display_hist(app);
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            Loadpdata_mddButtonPushed(app, event);
        end

        % Value changed function: FrqdiffEditField
        function FrqdiffEditFieldValueChanged(app, event)
            display_hist(app);
        end

        % Value changed function: MinSelectedFrqDiff
        function MinSelectedFrqDiffValueChanged(app, event)
            if app.MinSelectedFrqDiff.Value >= app.MaxSelectedFrqDiff.Value
                app.MaxSelectedFrqDiff.Value = app.MinSelectedFrqDiff.Value +0.1;
            end
            display_hist(app);
        end

        % Value changed function: MaxSelectedFrqDiff
        function MaxSelectedFrqDiffValueChanged(app, event)
            if app.MaxSelectedFrqDiff.Value <= app.MinSelectedFrqDiff.Value
                app.MinSelectedFrqDiff.Value = app.MaxSelectedFrqDiff.Value -0.1;
            end
            display_hist(app);
        end

        % Value changed function: SelectionDropDownFrqDiff
        function SelectionDropDownFrqDiffValueChanged(app, event)
            if strcmp(app.SelectionDropDownFrqDiff.Value,'Diso')
                app.MinSelectedFrqDiff.Value = -2.5;
                app.MaxSelectedFrqDiff.Value = 2.5;
            end
            if strcmp(app.SelectionDropDownFrqDiff.Value,'Ddelta')
                app.MinSelectedFrqDiff.Value = -1;
                app.MaxSelectedFrqDiff.Value = 1;
            end
            display_hist(app);
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            app.s.slice = round(app.SliceSlider.Value);
            % Display map
            display_map(app);
            if isfield(app.s,'Map_selected')
                display_weight_map(app);
            end
        end

        % Value changed function: MaxSelectedWeights
        function MaxSelectedWeightsValueChanged(app, event)
            if app.MaxSelectedWeights.Value <= app.MinSelectedWeights.Value
                app.MinSelectedWeights.Value = app.MaxSelectedWeights.Value -1;
            end
            display_hist(app);
        end

        % Value changed function: MinSelectedWeights
        function MinSelectedWeightsValueChanged(app, event)
            if app.MinSelectedWeights.Value >= app.MaxSelectedWeights.Value
                app.MaxSelectedWeights.Value = app.MinSelectedWeights.Value +1;
            end
            display_hist(app);
        end

        % Value changed function: SelectionDropDownDistX
        function SelectionDropDownDistXValueChanged(app, event)
            display_2D_dist(app);
        end

        % Value changed function: SelectionDropDownDistY
        function SelectionDropDownDistYValueChanged(app, event)
            display_2D_dist(app);
        end

        % Value changed function: NfreqEditField
        function NfreqEditFieldValueChanged(app, event)
            display_2D_dist(app);
        end

        % Value changed function: CheckBoxComp1
        function CheckBoxComp1ValueChanged(app, event)
            display_hist(app);
        end

        % Value changed function: CheckBoxComp2
        function CheckBoxComp2ValueChanged(app, event)
            display_hist(app);
        end

        % Value changed function: CheckBoxComp3
        function CheckBoxComp3ValueChanged(app, event)
            display_hist(app);
        end

        % Value changed function: CheckBoxComp4
        function CheckBoxComp4ValueChanged(app, event)
            display_hist(app);
        end

        % Value changed function: CheckBoxComp5
        function CheckBoxComp5ValueChanged(app, event)
            display_hist(app);
        end

        % Value changed function: CheckBoxComp6
        function CheckBoxComp6ValueChanged(app, event)
            display_hist(app);
        end

        % Value changed function: CheckBoxComp7
        function CheckBoxComp7ValueChanged(app, event)
            display_hist(app);
        end

        % Value changed function: CheckBoxComp8
        function CheckBoxComp8ValueChanged(app, event)
            display_hist(app);
        end

        % Value changed function: CheckBoxComp9
        function CheckBoxComp9ValueChanged(app, event)
            display_hist(app);
        end

        % Value changed function: CheckBoxComp10
        function CheckBoxComp10ValueChanged(app, event)
            display_hist(app);
        end

        % Value changed function: SigmaEditField
        function SigmaEditFieldValueChanged(app, event)
            display_2D_dist(app);
        end

        % Value changed function: ReverseorderCheckBox
        function ReverseorderCheckBoxValueChanged(app, event)
            for nmap = 1:app.s.Nmaps
                map_nam = app.s.map_nams{nmap};
                if size(app.s.pmaps.(map_nam),1)~=3
                    app.s.pmaps.(map_nam) = flip(app.s.pmaps.(map_nam),3);
                else
                    app.s.pmaps.(map_nam) = flip(app.s.pmaps.(map_nam),4);
                end
            end
            display_map(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesMap = uiaxes(app.UIFigure);
            xlabel(app.UIAxesMap, 'X')
            ylabel(app.UIAxesMap, 'Y')
            app.UIAxesDiso = uiaxes(app.UIFigure);
            title(app.UIAxesDiso, 'D_{iso}')
            app.UIAxesDiso.YTick = [];
            app.UIAxesDiso.YTickLabel = '';
            app.UIAxesDdelta = uiaxes(app.UIFigure);
            title(app.UIAxesDdelta, 'D_{delta}²')
            app.UIAxesDdelta.YTick = [];
            app.UIAxesDdelta.YTickLabel = '';
            app.UIAxesR1 = uiaxes(app.UIFigure);
            title(app.UIAxesR1, 'R_{1}')
            app.UIAxesR1.YTick = [];
            app.UIAxesR1.YTickLabel = '';
            app.UIAxesR2 = uiaxes(app.UIFigure);
            title(app.UIAxesR2, 'R_{2}')
            app.UIAxesR2.YTick = [];
            app.UIAxesR2.YTickLabel = '';
            app.UIAxesMapSelected = uiaxes(app.UIFigure);
            xlabel(app.UIAxesMapSelected, 'X')
            ylabel(app.UIAxesMapSelected, 'Y')
            app.UIAxesFrqDiff = uiaxes(app.UIFigure);
            title(app.UIAxesFrqDiff, 'Frq difference')
            app.UIAxesFrqDiff.YTick = [];
            app.UIAxesFrqDiff.YTickLabel = '';
            app.UIAxesWeights = uiaxes(app.UIFigure);
            title(app.UIAxesWeights, 'Weights')
            app.UIAxesWeights.YTick = [];
            app.UIAxesWeights.YTickLabel = '';
            app.UIAxesProfDistX = uiaxes(app.UIFigure);
            app.UIAxesProfDistX.XTick = [];
            app.UIAxesProfDistX.XTickLabel = '';
            app.UIAxesProfDistX.YTick = [];
            app.UIAxesProfDistX.YTickLabel = '';
            app.UIAxesDist2D = uiaxes(app.UIFigure);
            app.UIAxesDist2D.YAxisLocation = 'right';
            app.UIAxesProfDistY = uiaxes(app.UIFigure);
            app.UIAxesProfDistY.XDir = 'reverse';
            app.UIAxesProfDistY.XAxisLocation = 'top';
            app.UIAxesProfDistY.XTick = [];
            app.UIAxesProfDistY.XTickLabel = '';
            app.UIAxesProfDistY.YTick = [];
            app.UIAxesProfDistY.YTickLabel = '';
            app.LoaddataPanel = uipanel(app.UIFigure);
            app.LoaddataPanel.Title = 'Load data';
            app.MethodDropDownLabel = uilabel(app.LoaddataPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.LoaddataPanel);
            app.MethodDropDown.Items = {'dtor1r2d', 'dtr1r2d'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'dtor1r2d';
            app.Loadpdata_mddButton = uibutton(app.LoaddataPanel, 'push');
            app.Loadpdata_mddButton.ButtonPushedFcn = createCallbackFcn(app, @Loadpdata_mddButtonPushed, true);
            app.Loadpdata_mddButton.Text = 'Load pdata_mdd';
            app.MapDropDownLabel = uilabel(app.UIFigure);
            app.MapDropDownLabel.Text = 'Map';
            app.MapDropDown = uidropdown(app.UIFigure);
            app.MapDropDown.Items = {'fractions', 'mdiso'};
            app.MapDropDown.ValueChangedFcn = createCallbackFcn(app, @MapDropDownValueChanged, true);
            app.MapDropDown.Value = 'fractions';
            app.SelectionDropDownLabel = uilabel(app.UIFigure);
            app.SelectionDropDownLabel.Text = 'Selection';
            app.SelectionDropDown = uidropdown(app.UIFigure);
            app.SelectionDropDown.Items = {'Point', 'ROI'};
            app.SelectionDropDown.Value = 'Point';
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Text = 'Draw';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.NbinhistEditFieldLabel = uilabel(app.ParametersPanel);
            app.NbinhistEditFieldLabel.Text = 'Nbin hist';
            app.NbinhistEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.NbinhistEditField.ValueChangedFcn = createCallbackFcn(app, @NbinhistEditFieldValueChanged, true);
            app.NbinhistEditField.Value = 64;
            app.OmegafrqEditFieldLabel = uilabel(app.ParametersPanel);
            app.OmegafrqEditFieldLabel.Text = 'Omega frq';
            app.OmegafrqEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.OmegafrqEditField.ValueChangedFcn = createCallbackFcn(app, @OmegafrqEditFieldValueChanged, true);
            app.OmegafrqEditField.Value = 50;
            app.FrqdiffEditFieldLabel = uilabel(app.ParametersPanel);
            app.FrqdiffEditFieldLabel.Text = 'Frq diff';
            app.FrqdiffEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.FrqdiffEditField.ValueChangedFcn = createCallbackFcn(app, @FrqdiffEditFieldValueChanged, true);
            app.FrqdiffEditField.Value = 150;
            app.NFrqdiffLabel = uilabel(app.ParametersPanel);
            app.NFrqdiffLabel.Text = 'N Frq diff';
            app.NfreqEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.NfreqEditField.Limits = [1 Inf];
            app.NfreqEditField.ValueChangedFcn = createCallbackFcn(app, @NfreqEditFieldValueChanged, true);
            app.NfreqEditField.Value = 3;
            app.MinSelectedEditFieldLabel = uilabel(app.UIFigure);
            app.MinSelectedEditFieldLabel.Text = 'Min Selected';
            app.MinSelectedDiso = uieditfield(app.UIFigure, 'numeric');
            app.MinSelectedDiso.ValueChangedFcn = createCallbackFcn(app, @MinSelectedDisoValueChanged, true);
            app.MaxSelectedEditFieldLabel = uilabel(app.UIFigure);
            app.MaxSelectedEditFieldLabel.Text = 'Max Selected';
            app.MaxSelectedDiso = uieditfield(app.UIFigure, 'numeric');
            app.MaxSelectedDiso.ValueChangedFcn = createCallbackFcn(app, @MaxSelectedDisoValueChanged, true);
            app.MaxSelectedDiso.Value = 5;
            app.MinSelectedEditFieldLabel_2 = uilabel(app.UIFigure);
            app.MinSelectedEditFieldLabel_2.Text = 'Min Selected';
            app.MinSelectedDdelta = uieditfield(app.UIFigure, 'numeric');
            app.MinSelectedDdelta.ValueChangedFcn = createCallbackFcn(app, @MinSelectedDdeltaValueChanged, true);
            app.MaxSelectedEditFieldLabel_2 = uilabel(app.UIFigure);
            app.MaxSelectedEditFieldLabel_2.Text = 'Max Selected';
            app.MaxSelectedDdelta = uieditfield(app.UIFigure, 'numeric');
            app.MaxSelectedDdelta.ValueChangedFcn = createCallbackFcn(app, @MaxSelectedDdeltaValueChanged, true);
            app.MaxSelectedDdelta.Value = 1;
            app.MinSelectedEditFieldLabel_3 = uilabel(app.UIFigure);
            app.MinSelectedEditFieldLabel_3.Text = 'Min Selected';
            app.MinSelectedR1 = uieditfield(app.UIFigure, 'numeric');
            app.MinSelectedR1.ValueChangedFcn = createCallbackFcn(app, @MinSelectedR1ValueChanged, true);
            app.MaxSelectedEditFieldLabel_3 = uilabel(app.UIFigure);
            app.MaxSelectedEditFieldLabel_3.Text = 'Max Selected';
            app.MaxSelectedR1 = uieditfield(app.UIFigure, 'numeric');
            app.MaxSelectedR1.ValueChangedFcn = createCallbackFcn(app, @MaxSelectedR1ValueChanged, true);
            app.MaxSelectedR1.Value = 5;
            app.MinSelectedEditFieldLabel_4 = uilabel(app.UIFigure);
            app.MinSelectedEditFieldLabel_4.Text = 'Min Selected';
            app.MinSelectedR2 = uieditfield(app.UIFigure, 'numeric');
            app.MinSelectedR2.ValueChangedFcn = createCallbackFcn(app, @MinSelectedR2ValueChanged, true);
            app.MaxSelectedEditFieldLabel_4 = uilabel(app.UIFigure);
            app.MaxSelectedEditFieldLabel_4.Text = 'Max Selected';
            app.MaxSelectedR2 = uieditfield(app.UIFigure, 'numeric');
            app.MaxSelectedR2.ValueChangedFcn = createCallbackFcn(app, @MaxSelectedR2ValueChanged, true);
            app.MaxSelectedR2.Value = 100;
            app.MyDORR1R2distributionviewerLabel = uilabel(app.UIFigure);
            app.MyDORR1R2distributionviewerLabel.Text = 'My DOR-R1-R2 distribution viewer';
            app.LabelNI = uilabel(app.UIFigure);
            app.LabelNI.Text = '';
            app.LabelNJ = uilabel(app.UIFigure);
            app.LabelNJ.Text = '';
            app.SelectionDropDown_2Label = uilabel(app.UIFigure);
            app.SelectionDropDown_2Label.Text = 'Selection';
            app.SelectionDropDownFrqDiff = uidropdown(app.UIFigure);
            app.SelectionDropDownFrqDiff.Items = {'Diso', 'Ddelta'};
            app.SelectionDropDownFrqDiff.ValueChangedFcn = createCallbackFcn(app, @SelectionDropDownFrqDiffValueChanged, true);
            app.SelectionDropDownFrqDiff.Value = 'Diso';
            app.MinSelectedEditFieldLabel_5 = uilabel(app.UIFigure);
            app.MinSelectedEditFieldLabel_5.Text = 'Min Selected';
            app.MinSelectedFrqDiff = uieditfield(app.UIFigure, 'numeric');
            app.MinSelectedFrqDiff.ValueChangedFcn = createCallbackFcn(app, @MinSelectedFrqDiffValueChanged, true);
            app.MaxSelectedEditFieldLabel_5 = uilabel(app.UIFigure);
            app.MaxSelectedEditFieldLabel_5.Text = 'Max Selected';
            app.MaxSelectedFrqDiff = uieditfield(app.UIFigure, 'numeric');
            app.MaxSelectedFrqDiff.ValueChangedFcn = createCallbackFcn(app, @MaxSelectedFrqDiffValueChanged, true);
            app.SliceSliderLabel = uilabel(app.UIFigure);
            app.SliceSliderLabel.Text = 'Slice';
            app.SliceSlider = uislider(app.UIFigure);
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.MaxSelectedEditFieldLabel_6 = uilabel(app.UIFigure);
            app.MaxSelectedEditFieldLabel_6.Text = 'Max Selected';
            app.MaxSelectedWeights = uieditfield(app.UIFigure, 'numeric');
            app.MaxSelectedWeights.ValueChangedFcn = createCallbackFcn(app, @MaxSelectedWeightsValueChanged, true);
            app.MaxSelectedWeights.Value = 200;
            app.MinSelectedEditFieldLabel_6 = uilabel(app.UIFigure);
            app.MinSelectedEditFieldLabel_6.Text = 'Min Selected';
            app.MinSelectedWeights = uieditfield(app.UIFigure, 'numeric');
            app.MinSelectedWeights.ValueChangedFcn = createCallbackFcn(app, @MinSelectedWeightsValueChanged, true);
            app.XaxisLabel = uilabel(app.UIFigure);
            app.XaxisLabel.Text = 'X-axis';
            app.SelectionDropDownDistX = uidropdown(app.UIFigure);
            app.SelectionDropDownDistX.Items = {'Diso', 'Ddelta', 'R1', 'R2', 'Freq'};
            app.SelectionDropDownDistX.ValueChangedFcn = createCallbackFcn(app, @SelectionDropDownDistXValueChanged, true);
            app.SelectionDropDownDistX.Value = 'Diso';
            app.YaxisLabel = uilabel(app.UIFigure);
            app.YaxisLabel.Text = 'Y-axis';
            app.SelectionDropDownDistY = uidropdown(app.UIFigure);
            app.SelectionDropDownDistY.Items = {'Diso', 'Ddelta', 'R1', 'R2', 'Freq'};
            app.SelectionDropDownDistY.ValueChangedFcn = createCallbackFcn(app, @SelectionDropDownDistYValueChanged, true);
            app.SelectionDropDownDistY.Value = 'Ddelta';
            app.ComponentsPanel = uipanel(app.UIFigure);
            app.ComponentsPanel.Title = 'Components';
            app.CheckBoxComp1 = uicheckbox(app.ComponentsPanel);
            app.CheckBoxComp1.ValueChangedFcn = createCallbackFcn(app, @CheckBoxComp1ValueChanged, true);
            app.CheckBoxComp1.Value = true;
            app.CheckBoxComp2 = uicheckbox(app.ComponentsPanel);
            app.CheckBoxComp2.ValueChangedFcn = createCallbackFcn(app, @CheckBoxComp2ValueChanged, true);
            app.CheckBoxComp2.Value = true;
            app.CheckBoxComp3 = uicheckbox(app.ComponentsPanel);
            app.CheckBoxComp3.ValueChangedFcn = createCallbackFcn(app, @CheckBoxComp3ValueChanged, true);
            app.CheckBoxComp3.Value = true;
            app.CheckBoxComp4 = uicheckbox(app.ComponentsPanel);
            app.CheckBoxComp4.ValueChangedFcn = createCallbackFcn(app, @CheckBoxComp4ValueChanged, true);
            app.CheckBoxComp4.Value = true;
            app.CheckBoxComp5 = uicheckbox(app.ComponentsPanel);
            app.CheckBoxComp5.ValueChangedFcn = createCallbackFcn(app, @CheckBoxComp5ValueChanged, true);
            app.CheckBoxComp5.Value = true;
            app.Label = uilabel(app.ComponentsPanel);
            app.Label.Text = ' 1     2     3     4      5';
            app.CheckBoxComp6 = uicheckbox(app.ComponentsPanel);
            app.CheckBoxComp6.ValueChangedFcn = createCallbackFcn(app, @CheckBoxComp6ValueChanged, true);
            app.CheckBoxComp6.Value = true;
            app.CheckBoxComp7 = uicheckbox(app.ComponentsPanel);
            app.CheckBoxComp7.ValueChangedFcn = createCallbackFcn(app, @CheckBoxComp7ValueChanged, true);
            app.CheckBoxComp7.Value = true;
            app.CheckBoxComp8 = uicheckbox(app.ComponentsPanel);
            app.CheckBoxComp8.ValueChangedFcn = createCallbackFcn(app, @CheckBoxComp8ValueChanged, true);
            app.CheckBoxComp8.Value = true;
            app.CheckBoxComp9 = uicheckbox(app.ComponentsPanel);
            app.CheckBoxComp9.ValueChangedFcn = createCallbackFcn(app, @CheckBoxComp9ValueChanged, true);
            app.CheckBoxComp9.Value = true;
            app.CheckBoxComp10 = uicheckbox(app.ComponentsPanel);
            app.CheckBoxComp10.ValueChangedFcn = createCallbackFcn(app, @CheckBoxComp10ValueChanged, true);
            app.CheckBoxComp10.Value = true;
            app.Label_2 = uilabel(app.ComponentsPanel);
            app.Label_2.Text = ' 6      7     8     9     10';
            app.ReverseorderCheckBox = uicheckbox(app.UIFigure);
            app.ReverseorderCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverseorderCheckBoxValueChanged, true);
            app.ReverseorderCheckBox.Text = 'Reverse order';
            app.SigmaEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaEditField.Limits = [0.0001 10];
            app.SigmaEditField.ValueChangedFcn = createCallbackFcn(app, @SigmaEditFieldValueChanged, true);
            app.SigmaEditField.Value = 3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = my_DOR_distrib_viewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end